classdef CrossSectionApp6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Nerve_Cross_Section           matlab.ui.Figure
        EvaluateButton                matlab.ui.control.Button
        PercentagecompleteGaugeLabel  matlab.ui.control.Label
        PercentagecompleteGauge       matlab.ui.control.Gauge
        TabGroup                      matlab.ui.container.TabGroup
        ActivatedAxonsTab             matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        ExternalpotentialandMembranepotentialTab  matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        TimeSliderLabel               matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        UIAxes_4                      matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        StimulationelectrodepotentialsTab  matlab.ui.container.Tab
        Electrode1EditField_2         matlab.ui.control.NumericEditField
        Electrode1EditField_3         matlab.ui.control.NumericEditField
        Electrode1EditField_4         matlab.ui.control.NumericEditField
        Electrode1EditField_5         matlab.ui.control.NumericEditField
        Electrode1EditField_6         matlab.ui.control.NumericEditField
        Electrode1EditField_7         matlab.ui.control.NumericEditField
        Electrode1EditField_8         matlab.ui.control.NumericEditField
        Electrode1EditField_9         matlab.ui.control.NumericEditField
        Amp1Label                     matlab.ui.control.Label
        PWLabel                       matlab.ui.control.Label
        IPILabel                      matlab.ui.control.Label
        Electrode1EditField_10        matlab.ui.control.NumericEditField
        Electrode1EditField_11        matlab.ui.control.NumericEditField
        Electrode1EditField_12        matlab.ui.control.NumericEditField
        Electrode1EditField_13        matlab.ui.control.NumericEditField
        Electrode1EditField_14        matlab.ui.control.NumericEditField
        Electrode1EditField_15        matlab.ui.control.NumericEditField
        Electrode1EditField_16        matlab.ui.control.NumericEditField
        Electrode1EditField_17        matlab.ui.control.NumericEditField
        Amp1Label_2                   matlab.ui.control.Label
        PWLabel_2                     matlab.ui.control.Label
        IPILabel_2                    matlab.ui.control.Label
        Electrode1EditFieldLabel      matlab.ui.control.Label
        Electrode1EditField           matlab.ui.control.NumericEditField
        Electrode2EditFieldLabel      matlab.ui.control.Label
        Electrode2EditField           matlab.ui.control.NumericEditField
        Electrode3EditFieldLabel      matlab.ui.control.Label
        Electrode3EditField           matlab.ui.control.NumericEditField
        Electrode4EditFieldLabel      matlab.ui.control.Label
        Electrode4EditField           matlab.ui.control.NumericEditField
        Electrode5EditFieldLabel      matlab.ui.control.Label
        Electrode5EditField           matlab.ui.control.NumericEditField
        Electrode6EditFieldLabel      matlab.ui.control.Label
        Electrode6EditField           matlab.ui.control.NumericEditField
        Electrode7EditFieldLabel      matlab.ui.control.Label
        Electrode7EditField           matlab.ui.control.NumericEditField
        Electrode8EditFieldLabel      matlab.ui.control.Label
        Electrode8EditField           matlab.ui.control.NumericEditField
        Electrode1EditField_18        matlab.ui.control.NumericEditField
        Electrode1EditField_19        matlab.ui.control.NumericEditField
        Electrode1EditField_20        matlab.ui.control.NumericEditField
        Electrode1EditField_21        matlab.ui.control.NumericEditField
        Amp2Label                     matlab.ui.control.Label
        Electrode1EditField_22        matlab.ui.control.NumericEditField
        Electrode1EditField_23        matlab.ui.control.NumericEditField
        Electrode1EditField_24        matlab.ui.control.NumericEditField
        Electrode1EditField_25        matlab.ui.control.NumericEditField
        Amp2Label_2                   matlab.ui.control.Label
        HelpTab                       matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        Amp1Amplitudeof1stphaseofstimulationLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Ve
        Vm
        axons
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('Help.png','Parent',app.UIAxes2);
            nerveR = 1;              % Radius of the nerve (mm)
            elDist = 0.05;           % Electrode distance (cm)
            nElectrodes = 8;
            theta = 0 : 2 * pi / nElectrodes : 2 * pi - 0.01;    
            for i=1:length(theta)
                scatter(app.UIAxes,(nerveR + elDist) * sin(theta(i)),(nerveR + elDist) * cos(theta(i)),[],[1 0 0],'filled'); 
                hold(app.UIAxes,'on');
                text(app.UIAxes,(nerveR + elDist + 0.1) * sin(theta(i)) ,(nerveR + elDist + 0.1) * cos(theta(i)),num2str(i))
            end
            % Plot the nerve outline
            th = 0:pi/50:2*pi;
            xunit = 1 * cos(th);
            yunit = 1 * sin(th);
            plot(app.UIAxes, xunit, yunit, 'Color',[0,0,0]);
            plot(app.UIAxes_2, xunit, yunit, 'Color',[0,0,0]);
            plot(app.UIAxes_4, xunit, yunit, 'Color',[0,0,0]);
        end

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            cla(app.UIAxes);
            
            nerveR = 1;              % Radius of the nerve (mm)
            elDist = 0.05;           % Electrode distance (cm)
            nElectrodes = 8;
            theta = 0 : 2 * pi / nElectrodes : 2 * pi - 0.01;    
            for i=1:length(theta)
                scatter(app.UIAxes,(nerveR + elDist) * sin(theta(i)),(nerveR + elDist) * cos(theta(i)),[],[1 0 0],'filled'); 
                hold(app.UIAxes,'on');
                text(app.UIAxes,(nerveR + elDist + 0.1) * sin(theta(i)) ,(nerveR + elDist + 0.1) * cos(theta(i)),num2str(i))
            end
            % Plot the nerve outline
            th = 0:pi/50:2*pi;
            xunit = 1 * cos(th);
            yunit = 1 * sin(th);
            plot(app.UIAxes, xunit, yunit, 'Color',[0,0,0]);
            plot(app.UIAxes_2, xunit, yunit, 'Color',[0,0,0]);
            plot(app.UIAxes_4, xunit, yunit, 'Color',[0,0,0]);
                        
            
            amp1 = zeros(8,1);
            amp2 = zeros(8,1);
            pw = zeros(8,1);
            ipi = zeros(8,1);
            
            amp1(1) = app.Electrode1EditField.Value;
            amp2(1) = app.Electrode1EditField_18.Value;
            pw(1) = app.Electrode1EditField_2.Value;
            ipi(1) = app.Electrode1EditField_6.Value;
            
            amp1(2) = app.Electrode2EditField.Value;
            amp2(2) = app.Electrode1EditField_19.Value;
            pw(2) = app.Electrode1EditField_3.Value;
            ipi(2) = app.Electrode1EditField_7.Value;
            
            amp1(3) = app.Electrode3EditField.Value;
            amp2(3) = app.Electrode1EditField_20.Value;
            pw(3) = app.Electrode1EditField_4.Value;
            ipi(3) = app.Electrode1EditField_8.Value;
            
            amp1(4) = app.Electrode4EditField.Value;
            amp2(4) = app.Electrode1EditField_21.Value;
            pw(4) = app.Electrode1EditField_5.Value;
            ipi(4) = app.Electrode1EditField_9.Value;
            
            amp1(5) = app.Electrode5EditField.Value;
            amp2(5) = app.Electrode1EditField_22.Value;
            pw(5) = app.Electrode1EditField_10.Value;
            ipi(5) = app.Electrode1EditField_14.Value;
            
            amp1(6) = app.Electrode6EditField.Value;
            amp2(6) = app.Electrode1EditField_23.Value;
            pw(6) = app.Electrode1EditField_11.Value;
            ipi(6) = app.Electrode1EditField_15.Value;
            
            amp1(7) = app.Electrode7EditField.Value;
            amp2(7) = app.Electrode1EditField_24.Value;
            pw(7) = app.Electrode1EditField_12.Value;
            ipi(7) = app.Electrode1EditField_16.Value;
            
            amp1(8) = app.Electrode8EditField.Value;
            amp2(8) = app.Electrode1EditField_25.Value;
            pw(8) = app.Electrode1EditField_13.Value;
            ipi(8) = app.Electrode1EditField_17.Value;
           
            [app.axons, app.Ve, app.Vm] = evaluateActivation6(amp1,amp2,ipi,pw,8,app.UIAxes,app.PercentagecompleteGauge);
            hold(app.UIAxes,'on')
            
            % Extract axons which are activated ( axon(:,3) = 1 )
            activatedAxons = app.axons(app.axons(:,3) == 1, 1:2);
            
            % PLot activated axons
            scatter(app.UIAxes,activatedAxons(:,1),activatedAxons(:,2),[],[0 1 0],'filled');
            
            % Plot place holders for all axons
            scatter(app.UIAxes, app.axons(:, 1), app.axons(:, 2), [], [0 0 0]);

            % Plot the heat maps - external potential (Ve)
            scatter(app.UIAxes_2, app.axons(:, 1), app.axons(:, 2), [], app.Ve(:, floor(app.TimeSlider.Value)),'filled');
            colormap(app.UIAxes_2, jet);
            colorbar(app.UIAxes_2);
            hold(app.UIAxes_2,'on')
            
            % Plot the heat maps - membrane voltage (Vm)
            scatter(app.UIAxes_4, app.axons(:, 1), app.axons(:, 2), [], app.Vm(:, floor(app.TimeSlider.Value)),'filled');
            colormap(app.UIAxes_4, jet);
            colorbar(app.UIAxes_4);
            hold(app.UIAxes_4,'on')
            
            % Plot the nerve outline
            th = 0:pi/50:2*pi;
            xunit = 1 * cos(th);
            yunit = 1 * sin(th);
            plot(app.UIAxes, xunit, yunit, 'Color',[0,0,0]);
            plot(app.UIAxes_2, xunit, yunit, 'Color',[0,0,0]);
            plot(app.UIAxes_4, xunit, yunit, 'Color',[0,0,0]);
        end

        % Callback function
        function NumberofelectrodesDropDownValueChanged(app, event)
            
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            % Plot the heat maps - external potential (Ve)
            scatter(app.UIAxes_2, app.axons(:, 1), app.axons(:, 2), [], app.Ve(:, floor(app.TimeSlider.Value)),'filled');
            colormap(app.UIAxes_2, jet);
            colorbar(app.UIAxes_2);
            hold(app.UIAxes_2,'on')
            
            % Plot the heat maps - membrane voltage (Vm)
            scatter(app.UIAxes_4, app.axons(:, 1), app.axons(:, 2), [], app.Vm(:, floor(app.TimeSlider.Value)),'filled');
            colormap(app.UIAxes_4, jet);
            colorbar(app.UIAxes_4);
            hold(app.UIAxes_4,'on')
            
            % Plot the nerve outline
            th = 0:pi/50:2*pi;
            xunit = 1 * cos(th);
            yunit = 1 * sin(th);
            plot(app.UIAxes_2, xunit, yunit, 'Color',[0,0,0]);
            plot(app.UIAxes_4, xunit, yunit, 'Color',[0,0,0]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Nerve_Cross_Section = uifigure;
            app.Nerve_Cross_Section.Name = 'Nerve activation visualization tool';
            app.EvaluateButton = uibutton(app.Nerve_Cross_Section, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.PercentagecompleteGaugeLabel = uilabel(app.Nerve_Cross_Section);
            app.PercentagecompleteGaugeLabel.Text = 'Percentage complete';
            app.PercentagecompleteGauge = uigauge(app.Nerve_Cross_Section, 'circular');
            app.TabGroup = uitabgroup(app.Nerve_Cross_Section);
            app.ActivatedAxonsTab = uitab(app.TabGroup);
            app.ActivatedAxonsTab.Title = 'Activated Axons';
            app.UIAxes = uiaxes(app.ActivatedAxonsTab);
            title(app.UIAxes, 'Activation across cross section of nerve')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-1.2 1.2];
            app.UIAxes.YLim = [-1.2 1.2];
            app.UIAxes.XTick = [-1.25 -1 -0.5 0 0.5 1 1.25];
            app.UIAxes.XTickLabel = {'-1.25'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.25'};
            app.UIAxes.YTick = [-1.25 -1 -0.5 0 0.5 1 1.25];
            app.UIAxes.YTickLabel = {'-1.25'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.25'};
            app.ExternalpotentialandMembranepotentialTab = uitab(app.TabGroup);
            app.ExternalpotentialandMembranepotentialTab.Title = 'External potential and Membrane potential';
            app.UIAxes_2 = uiaxes(app.ExternalpotentialandMembranepotentialTab);
            title(app.UIAxes_2, 'External potential across cross section of nerve')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [-1.2 1.2];
            app.UIAxes_2.YLim = [-1.2 1.2];
            app.UIAxes_2.XTick = [-1.2 -1 -0.5 0 0.5 1 1.2];
            app.UIAxes_2.XTickLabel = {'-1.2'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.2'};
            app.UIAxes_2.YTick = [-1.2 -1 -0.5 0 0.5 1 1.2];
            app.UIAxes_2.YTickLabel = {'-1.2'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.2'};
            app.TimeSliderLabel = uilabel(app.ExternalpotentialandMembranepotentialTab);
            app.TimeSliderLabel.Text = 'Time ';
            app.TimeSlider = uislider(app.ExternalpotentialandMembranepotentialTab);
            app.TimeSlider.Limits = [0 2000];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.Value = 580;
            app.UIAxes_4 = uiaxes(app.ExternalpotentialandMembranepotentialTab);
            title(app.UIAxes_4, 'Membrane potential across cross section of nerve')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_4.XLim = [-1.2 1.2];
            app.UIAxes_4.YLim = [-1.2 1.2];
            app.UIAxes_4.XTick = [-1.2 -1 -0.5 0 0.5 1 1.2];
            app.UIAxes_4.XTickLabel = {'-1.2'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.2'};
            app.UIAxes_4.YTick = [-1.2 -1 -0.5 0 0.5 1 1.2];
            app.UIAxes_4.YTickLabel = {'-1.2'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.2'};
            app.TabGroup2 = uitabgroup(app.Nerve_Cross_Section);
            app.StimulationelectrodepotentialsTab = uitab(app.TabGroup2);
            app.StimulationelectrodepotentialsTab.Title = 'Stimulation electrode potentials';
            app.Electrode1EditField_2 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_2.Limits = [0 1000];
            app.Electrode1EditField_2.Value = 100;
            app.Electrode1EditField_3 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_3.Limits = [0 1000];
            app.Electrode1EditField_3.Value = 100;
            app.Electrode1EditField_4 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_4.Limits = [0 1000];
            app.Electrode1EditField_4.Value = 100;
            app.Electrode1EditField_5 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_5.Limits = [0 1000];
            app.Electrode1EditField_5.Value = 100;
            app.Electrode1EditField_6 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_6.Limits = [0 1000];
            app.Electrode1EditField_6.Value = 200;
            app.Electrode1EditField_7 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_7.Limits = [0 1000];
            app.Electrode1EditField_7.Value = 200;
            app.Electrode1EditField_8 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_8.Limits = [0 1000];
            app.Electrode1EditField_8.Value = 200;
            app.Electrode1EditField_9 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_9.Limits = [0 1000];
            app.Electrode1EditField_9.Value = 200;
            app.Amp1Label = uilabel(app.StimulationelectrodepotentialsTab);
            app.Amp1Label.Text = 'Amp 1';
            app.PWLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.PWLabel.Text = 'PW';
            app.IPILabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.IPILabel.Text = 'IPI';
            app.Electrode1EditField_10 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_10.Limits = [0 1000];
            app.Electrode1EditField_10.Value = 100;
            app.Electrode1EditField_11 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_11.Limits = [0 1000];
            app.Electrode1EditField_11.Value = 100;
            app.Electrode1EditField_12 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_12.Limits = [0 1000];
            app.Electrode1EditField_12.Value = 100;
            app.Electrode1EditField_13 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_13.Limits = [0 1000];
            app.Electrode1EditField_13.Value = 100;
            app.Electrode1EditField_14 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_14.Limits = [0 1000];
            app.Electrode1EditField_14.Value = 200;
            app.Electrode1EditField_15 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_15.Limits = [0 1000];
            app.Electrode1EditField_15.Value = 200;
            app.Electrode1EditField_16 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_16.Limits = [0 1000];
            app.Electrode1EditField_16.Value = 200;
            app.Electrode1EditField_17 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_17.Limits = [0 1000];
            app.Electrode1EditField_17.Value = 200;
            app.Amp1Label_2 = uilabel(app.StimulationelectrodepotentialsTab);
            app.Amp1Label_2.Text = 'Amp 1';
            app.PWLabel_2 = uilabel(app.StimulationelectrodepotentialsTab);
            app.PWLabel_2.Text = 'PW';
            app.IPILabel_2 = uilabel(app.StimulationelectrodepotentialsTab);
            app.IPILabel_2.Text = 'IPI';
            app.Electrode1EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode1EditFieldLabel.Text = 'Electrode 1';
            app.Electrode1EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField.Limits = [-1000 1000];
            app.Electrode1EditField.Value = -80;
            app.Electrode2EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode2EditFieldLabel.Text = 'Electrode 2';
            app.Electrode2EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode2EditField.Limits = [-1000 1000];
            app.Electrode2EditField.Value = -80;
            app.Electrode3EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode3EditFieldLabel.Text = 'Electrode 3';
            app.Electrode3EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode3EditField.Limits = [-1000 1000];
            app.Electrode3EditField.Value = -80;
            app.Electrode4EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode4EditFieldLabel.Text = 'Electrode 4';
            app.Electrode4EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode4EditField.Limits = [-1000 1000];
            app.Electrode4EditField.Value = -80;
            app.Electrode5EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode5EditFieldLabel.Text = 'Electrode 5';
            app.Electrode5EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode5EditField.Limits = [-1000 1000];
            app.Electrode5EditField.Value = -80;
            app.Electrode6EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode6EditFieldLabel.Text = 'Electrode 6';
            app.Electrode6EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode6EditField.Limits = [-1000 1000];
            app.Electrode6EditField.Value = -80;
            app.Electrode7EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode7EditFieldLabel.Text = 'Electrode 7';
            app.Electrode7EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode7EditField.Limits = [-1000 1000];
            app.Electrode7EditField.Value = -80;
            app.Electrode8EditFieldLabel = uilabel(app.StimulationelectrodepotentialsTab);
            app.Electrode8EditFieldLabel.Text = 'Electrode 8';
            app.Electrode8EditField = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode8EditField.Limits = [-1000 1000];
            app.Electrode8EditField.Value = -80;
            app.Electrode1EditField_18 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_18.Limits = [0 1000];
            app.Electrode1EditField_18.Value = 80;
            app.Electrode1EditField_19 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_19.Limits = [0 1000];
            app.Electrode1EditField_19.Value = 80;
            app.Electrode1EditField_20 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_20.Limits = [0 1000];
            app.Electrode1EditField_20.Value = 80;
            app.Electrode1EditField_21 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_21.Limits = [0 1000];
            app.Electrode1EditField_21.Value = 80;
            app.Amp2Label = uilabel(app.StimulationelectrodepotentialsTab);
            app.Amp2Label.Text = 'Amp 2';
            app.Electrode1EditField_22 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_22.Limits = [0 1000];
            app.Electrode1EditField_22.Value = 80;
            app.Electrode1EditField_23 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_23.Limits = [0 1000];
            app.Electrode1EditField_23.Value = 80;
            app.Electrode1EditField_24 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_24.Limits = [0 1000];
            app.Electrode1EditField_24.Value = 80;
            app.Electrode1EditField_25 = uieditfield(app.StimulationelectrodepotentialsTab, 'numeric');
            app.Electrode1EditField_25.Limits = [0 1000];
            app.Electrode1EditField_25.Value = 80;
            app.Amp2Label_2 = uilabel(app.StimulationelectrodepotentialsTab);
            app.Amp2Label_2.Text = 'Amp 2';
            app.HelpTab = uitab(app.TabGroup2);
            app.HelpTab.Title = 'Help';
            app.UIAxes2 = uiaxes(app.HelpTab);
            title(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Amp1Amplitudeof1stphaseofstimulationLabel = uilabel(app.HelpTab);
            app.Amp1Amplitudeof1stphaseofstimulationLabel.Text = {'Amp 1 - Amplitude of 1st phase of stimulation (uA)'; ''; 'Amp 2 - Amplitude of 2nd phase of stimulation (uA)'; ''; 'IPI - Inter phase interval (us)'; ''; 'PW - Pulse width of both phases (us)'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = CrossSectionApp6

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Nerve_Cross_Section)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Nerve_Cross_Section)
        end
    end
end